classdef MTO_Platform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MTOPlatformUIFigure          matlab.ui.Figure
        MTOPlatformGridLayout        matlab.ui.container.GridLayout
        MTOPlatformTabGroup          matlab.ui.container.TabGroup
        ExperimentModelTab           matlab.ui.container.Tab
        ExperimentsGridLayout        matlab.ui.container.GridLayout
        EPanel3                      matlab.ui.container.Panel
        EPanel3GridLayout            matlab.ui.container.GridLayout
        LogsTextAreaLabel            matlab.ui.control.Label
        ELogsTextArea                matlab.ui.control.TextArea
        ELogsClearButton             matlab.ui.control.Button
        ESaveDataButton              matlab.ui.control.Button
        EPanel1                      matlab.ui.container.Panel
        EP1GridLayout                matlab.ui.container.GridLayout
        EProblemsAddButton           matlab.ui.control.Button
        EAlgorithmsAddButton         matlab.ui.control.Button
        ERepsEditField               matlab.ui.control.NumericEditField
        ERunTimesEditFieldLabel      matlab.ui.control.Label
        EEndNumEditField             matlab.ui.control.NumericEditField
        EEndNumEditFieldLabel        matlab.ui.control.Label
        EPopSizeEditField            matlab.ui.control.NumericEditField
        EPopSizeEditFieldLabel       matlab.ui.control.Label
        EAlgorithmsListBox           matlab.ui.control.ListBox
        AlgorithmsListBox_2Label     matlab.ui.control.Label
        EProblemsListBox             matlab.ui.control.ListBox
        ProblemsListBox_2Label       matlab.ui.control.Label
        EndTypeLabel                 matlab.ui.control.Label
        EEndConditionDropDown        matlab.ui.control.DropDown
        EPanel2                      matlab.ui.container.Panel
        EP2GridLayout                matlab.ui.container.GridLayout
        EStartButton                 matlab.ui.control.Button
        EPauseButton                 matlab.ui.control.Button
        EStopButton                  matlab.ui.control.Button
        EAlgorithmsTree              matlab.ui.container.Tree
        EProblemsTree                matlab.ui.container.Tree
        ESelectedAlgorithmsLabel     matlab.ui.control.Label
        ESelectedProblemsLabel       matlab.ui.control.Label
        EAlgorithmsDelButton         matlab.ui.control.Button
        EProblemsDelButton           matlab.ui.control.Button
        ViewTableTab                 matlab.ui.container.Tab
        ViewTableGridLayout          matlab.ui.container.GridLayout
        VPanel1                      matlab.ui.container.Panel
        VP1GridLayout                matlab.ui.container.GridLayout
        VTableTabGroup               matlab.ui.container.TabGroup
        VFitnessTab                  matlab.ui.container.Tab
        TFitnessGridLayout           matlab.ui.container.GridLayout
        TFitnessUITable              matlab.ui.control.Table
        VFitnessStdTab               matlab.ui.container.Tab
        TFitnessStdGridLayout        matlab.ui.container.GridLayout
        TFitnessStdUITable           matlab.ui.control.Table
        VTimeTab                     matlab.ui.container.Tab
        TTimeTableGridLayout         matlab.ui.container.GridLayout
        TTimeUITable                 matlab.ui.control.Table
        VPanel2                      matlab.ui.container.Panel
        VP2GridLayout                matlab.ui.container.GridLayout
        VLogsTextArea                matlab.ui.control.TextArea
        VLogsClearButton             matlab.ui.control.Button
        VLogsTextAreaLabel           matlab.ui.control.Label
        VPanel3                      matlab.ui.container.Panel
        VP3GridLayout                matlab.ui.container.GridLayout
        YLimTypeDropDownLabel        matlab.ui.control.Label
        VYLimTypeDropDown            matlab.ui.control.DropDown
        FigureTypeDropDownLabel      matlab.ui.control.Label
        VFigureTypeDropDown          matlab.ui.control.DropDown
        TableTypeDropDownLabel       matlab.ui.control.Label
        VTableTypeDropDown           matlab.ui.control.DropDown
        VSaveResultButton            matlab.ui.control.Button
        VLoadDataButton              matlab.ui.control.Button
        TestModelTab                 matlab.ui.container.Tab
        TestGridLayout               matlab.ui.container.GridLayout
        TPGridLayout                 matlab.ui.container.GridLayout
        LogsTextArea_2Label_2        matlab.ui.control.Label
        TLogsTextArea                matlab.ui.control.TextArea
        TLogsClearButton             matlab.ui.control.Button
        MergeDataTab                 matlab.ui.container.Tab
        MergeDataGridLayout          matlab.ui.container.GridLayout
        MPanel1                      matlab.ui.container.Panel
        MP1GridLayout                matlab.ui.container.GridLayout
        MLoadDataButton              matlab.ui.control.Button
        MDeleteDataButton            matlab.ui.control.Button
        MDataTree                    matlab.ui.container.Tree
        MRepsMergeButton             matlab.ui.control.Button
        MProblemsMergeButton         matlab.ui.control.Button
        MAlgorithmsMergeButton       matlab.ui.control.Button
        MPanel2                      matlab.ui.container.Panel
        MP2GridLayout                matlab.ui.container.GridLayout
        LogsTextArea_2Label          matlab.ui.control.Label
        MLogsTextArea                matlab.ui.control.TextArea
        MLogsClearButton             matlab.ui.control.Button
        ESelectedAlgoContextMenu     matlab.ui.container.ContextMenu
        SelectedAlgoSelectAllMenu    matlab.ui.container.Menu
        MDataContextMenu             matlab.ui.container.ContextMenu
        SelectedAlgoSelectAllMenu_2  matlab.ui.container.Menu
        ESelectedProbContextMenu     matlab.ui.container.ContextMenu
        SelectedProbSelectAllMenu    matlab.ui.container.Menu
        EAlgorithmsContextMenu       matlab.ui.container.ContextMenu
        AlgorithmsSelectAllMenu      matlab.ui.container.Menu
        EProblemsContextMenu         matlab.ui.container.ContextMenu
        ProblemsSelectAllMenu        matlab.ui.container.Menu
    end

    properties (Access = public)
        data % data
        
        % Experiment
        Ereps % number of independent runs
        Epop_size % number of population size
        Eend_num % number of end condition
        Eresult % (problem, algorithm){wallclock, rep * convergence}
        Estop_flag % stop button clicked flag
    end
    
    methods (Access = public)
        
        function Eprintlog(app, str)
            % print log to Experiments logs text area
            
            if strcmp(app.ELogsTextArea.Value, '')
                app.ELogsTextArea.Value = str;
            else
                app.ELogsTextArea.Value = [app.ELogsTextArea.Value; str];
            end
            drawnow;
            scroll(app.ELogsTextArea, 'bottom');
        end
        
        function Tprintlog(app, str)
            % print log to Test logs text area
            
            if strcmp(app.TLogsTextArea.Value, '')
                app.TLogsTextArea.Value = str;
            else
                app.TLogsTextArea.Value = [app.TLogsTextArea.Value; str];
            end
            drawnow;
            scroll(app.TLogsTextArea, 'bottom');
        end
        
        function Vprintlog(app, str)
            % print log to View Table logs text area
            
            if strcmp(app.VLogsTextArea.Value, '')
                app.VLogsTextArea.Value = str;
            else
                app.VLogsTextArea.Value = [app.VLogsTextArea.Value; str];
            end
            drawnow;
            scroll(app.VLogsTextArea, 'bottom');
        end
        
        function Mprintlog(app, str)
            % print log to Merge Data logs text area
            
            if strcmp(app.MLogsTextArea.Value, '')
                app.MLogsTextArea.Value = str;
            else
                app.MLogsTextArea.Value = [app.MLogsTextArea.Value; str];
            end
            drawnow;
            scroll(app.MLogsTextArea, 'bottom');
        end
        
        function EloadAlgoProb(app)
            % load the algorithms .m file and problems .m file to listbox
            
            app.EAlgorithmsListBox.Items(:) = [];
            app.EProblemsListBox.Items(:) = [];
            algo_load = {};
            prob_load = {};
            algo_dir = dir('Algorithms/*m');
            prob_dir = dir('Problems/*.m');
            
            % Algorithms
            for i=1:length(algo_dir)
                algo_load  = [algo_load, algo_dir(i).name(1:end-2)];
            end
            algo_load(strcmp(algo_load, 'Algorithm')) = [];
            app.EAlgorithmsListBox.Items = algo_load;
            
            % Problems
            for i=1:length(prob_dir)
                prob_load  = [prob_load, prob_dir(i).name(1:end-2)];
            end
            prob_load(strcmp(prob_load, 'Problem')) = [];
            app.EProblemsListBox.Items = prob_load;
        end
        
        function EcheckPauseStopStatus(app)
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            
            if app.Estop_flag
                app.EstartEnable(true);
                error('User Stop');
            end
            
            if strcmp(app.EPauseButton.Text, 'Resume')
                waitfor(app.EPauseButton,'Text', 'Pause');
            end
        end
        
        function VresetTable(app, algo_cell, prob_cell, tasks_num_list)
            % reset the table data and name
            
            prob_row_cell = {};
            for prob = 1:length(prob_cell)
                for task = 1:tasks_num_list(prob)
                    prob_row_cell = [prob_row_cell, [prob_cell{prob}, num2str(task)]];
                end
            end
            app.TFitnessUITable.ColumnName = algo_cell;
            app.TFitnessStdUITable.ColumnName = algo_cell;
            app.TTimeUITable.ColumnName = algo_cell;
            app.TFitnessUITable.RowName = prob_row_cell;
            app.TFitnessStdUITable.RowName = prob_row_cell;
            app.TTimeUITable.RowName = prob_cell;
            app.TFitnessUITable.Data = [];
            app.TFitnessStdUITable.Data = [];
            app.TTimeUITable.Data = [];
        end
        
        function VupdateTable(app, data)
            % update fitness, std, time_used table
            
            for algo = 1:length(data.algo_cell)
                row_i = 1;
                for prob = 1:length(data.prob_cell)
                    tasks_num = data.tasks_num_list(prob);
                    for task = 1:tasks_num
                        convergence_task = data.result(prob, algo).convergence(task:tasks_num:end, :);
                        mean_end = mean(convergence_task(:, end));
                        std_end = std(convergence_task(:, end));
                        app.TFitnessUITable.Data(row_i, algo) = mean_end;
                        app.TFitnessStdUITable.Data(row_i, algo) = std_end;
                        row_i = row_i + 1;
                    end
                    app.TTimeUITable.Data(prob, algo) = data.result(prob, algo).clock_time;
                end
            end
            
            % highlight best value
            app.TFitnessUITable.removeStyle();
            app.TFitnessStdUITable.removeStyle();
            app.TTimeUITable.removeStyle();
            highlight = uistyle('BackgroundColor', [0.67,0.95,0.67]);
            worstlight = uistyle('BackgroundColor', [1.00,0.60,0.60]);
            row_i = 1;
            for prob = 1:length(data.prob_cell)
                tasks_num = data.tasks_num_list(prob);
                for task = 1:tasks_num
                    % best
                    [~, index] = min(app.TFitnessUITable.Data(row_i, :));
                    app.TFitnessUITable.addStyle(highlight, 'cell', [row_i, index]);
                    [~, index] = min(app.TFitnessStdUITable.Data(row_i, :));
                    app.TFitnessStdUITable.addStyle(highlight, 'cell', [row_i, index]);
                    
                    % worst
                    [~, index] = max(app.TFitnessUITable.Data(row_i, :));
                    app.TFitnessUITable.addStyle(worstlight, 'cell', [row_i, index]);
                    [~, index] = max(app.TFitnessStdUITable.Data(row_i, :));
                    app.TFitnessStdUITable.addStyle(worstlight, 'cell', [row_i, index]);
                    
                    row_i = row_i + 1;
                end
                % best
                [~, index] = min(app.TTimeUITable.Data(prob, :));
                app.TTimeUITable.addStyle(highlight, 'cell', [prob, index]);
                
                % worst
                [~, index] = max(app.TTimeUITable.Data(prob, :));
                app.TTimeUITable.addStyle(worstlight, 'cell', [prob, index]);
            end
            drawnow;
        end
        
        function VupdateTableProb(app, algo_cell, prob, tasks_num_list)
            % update fitness, std, time_used table problem
            
            for algo = 1:length(algo_cell)
                tasks_num = tasks_num_list(prob);
                row_i = sum(tasks_num_list(1:prob-1)) + 1;
                for task = 1:tasks_num
                    convergence_task = app.Eresult(prob, algo).convergence(task:tasks_num:end, :);
                    mean_end = mean(convergence_task(:, end));
                    std_end = std(convergence_task(:, end));
                    app.TFitnessUITable.Data(row_i, algo) = mean_end;
                    app.TFitnessStdUITable.Data(row_i, algo) = std_end;
                    row_i = row_i + 1;
                end
                app.TTimeUITable.Data(prob, algo) = app.Eresult(prob, algo).clock_time;
            end
            
            % highlight best value
            row_i = sum(tasks_num_list(1:prob-1)) + 1;
            highlight = uistyle('BackgroundColor', [0.67,0.95,0.67]);
            worstlight = uistyle('BackgroundColor', [1.00,0.60,0.60]);
            tasks_num = tasks_num_list(prob);
            for task = 1:tasks_num
                target_index = app.TFitnessUITable.StyleConfigurations.TargetIndex;
                style_row = reshape([target_index{:}], [2, length(target_index)]);
                if ~isempty(find(style_row(1, :) == row_i, 1))
                    app.TFitnessUITable.removeStyle(find(style_row(1, :) == row_i));
                end
                target_index = app.TFitnessStdUITable.StyleConfigurations.TargetIndex;
                style_row = reshape([target_index{:}], [2, length(target_index)]);
                if ~isempty(find(style_row(1, :) == row_i, 1))
                    app.TFitnessStdUITable.removeStyle(find(style_row(1, :) == row_i));
                end
                
                % best
                [~, index] = min(app.TFitnessUITable.Data(row_i, :));
                app.TFitnessUITable.addStyle(highlight, 'cell', [row_i, index]);
                [~, index] = min(app.TFitnessStdUITable.Data(row_i, :));
                app.TFitnessStdUITable.addStyle(highlight, 'cell', [row_i, index]);
                
                % worst
                [~, index] = max(app.TFitnessUITable.Data(row_i, :));
                app.TFitnessUITable.addStyle(worstlight, 'cell', [row_i, index]);
                [~, index] = max(app.TFitnessStdUITable.Data(row_i, :));
                app.TFitnessStdUITable.addStyle(worstlight, 'cell', [row_i, index]);
                row_i = row_i + 1;
            end
            target_index = app.TTimeUITable.StyleConfigurations.TargetIndex;
            style_row = reshape([target_index{:}], [2, length(target_index)]);
            if ~isempty(find(style_row(1, :) == prob, 1))
                app.TTimeUITable.removeStyle(find(style_row(1, :) == prob));
            end
            % best
            [~, index] = min(app.TTimeUITable.Data(prob, :));
            app.TTimeUITable.addStyle(highlight, 'cell', [prob, index]);
            
            % worst
            [~, index] = max(app.TTimeUITable.Data(prob, :));
            app.TTimeUITable.addStyle(worstlight, 'cell', [prob, index]);
            
            drawnow;
        end
        
        function EstartEnable(app, value)
            app.EStartButton.Enable = value;
            app.ERepsEditField.Enable = value;
            app.EPopSizeEditField.Enable = value;
            app.EEndConditionDropDown.Enable = value;
            app.EEndNumEditField.Enable = value;
            app.EAlgorithmsAddButton.Enable = value;
            app.EProblemsAddButton.Enable = value;
            app.EAlgorithmsListBox.Enable = value;
            app.EProblemsListBox.Enable = value;
            app.EAlgorithmsDelButton.Enable = value;
            app.EProblemsDelButton.Enable = value;
            app.EAlgorithmsTree.Enable = value;
            app.EProblemsTree.Enable = value;
            app.EPauseButton.Enable = ~value;
            app.EStopButton.Enable = ~value;
        end
        
        function result = McheckData(app)
            % check data num, pop size, iter num, eva num
            
            data_num = length(app.MDataTree.Children);
            if data_num < 2
                app.Mprintlog('!!! Add at least 2 data to merge !!!');
                result = false;
                return;
            end
            
            % check pop, iteration and evaluate
            pop_size = app.MDataTree.Children(1).NodeData.pop_size;
            iter_num = app.MDataTree.Children(1).NodeData.iter_num;
            eva_num = app.MDataTree.Children(1).NodeData.eva_num;
            for i = 2:data_num
                if app.MDataTree.Children(i).NodeData.pop_size ~= pop_size || ...
                        app.MDataTree.Children(i).NodeData.iter_num ~= iter_num || ...
                        app.MDataTree.Children(i).NodeData.eva_num ~= eva_num
                app.Mprintlog('!!! The data''s pop_size or iter_num or eva_num not equal !!!');
                result = false;
                return;
                end
            end
            result = true;
        end
        
        function result = McheckReps(app)
            % check reps
            
            data_num = length(app.MDataTree.Children);
            reps = app.MDataTree.Children(1).NodeData.reps;
            for i = 2:data_num
                if app.MDataTree.Children(i).NodeData.reps ~= reps
                    app.Mprintlog('!!! The data''s reps not equal !!!');
                    result = false;
                    return;
                end
            end
            result = true;
        end
        
        function result = McheckAlgorithms(app)
            % check algorithms
            
            data_num = length(app.MDataTree.Children);
            algo_cell = app.MDataTree.Children(1).NodeData.algo_cell;
            for i = 2:data_num
                for algo = 1:length(algo_cell)
                    if ~strcmp(app.MDataTree.Children(i).NodeData.algo_cell{algo}, algo_cell{algo})
                        app.Mprintlog('!!! The data''s algorithms not equal !!!');
                        result = false;
                        return;
                    end
                end
            end
            result = true;
        end
        
        function result = McheckProblems(app)
            % check problems
            
            data_num = length(app.MDataTree.Children);
            prob_cell = app.MDataTree.Children(1).NodeData.prob_cell;
            for i = 2:data_num
                for prob = 1:length(prob_cell)
                    if ~strcmp(app.MDataTree.Children(i).NodeData.prob_cell{prob}, prob_cell{prob})
                        app.Mprintlog('!!! The data''s problems not equal !!!');
                        result = false;
                        return;
                    end
                end
            end
            result = true;
        end
        
        function MsaveData(app, data_save)
            % save data to folder
            
            % check selected file name
            [file_name, dir_name] = uiputfile('data_save.mat');
            if file_name == 0
                app.Mprintlog('!!! User select canceled !!!');
                return;
            end
            
            % save data
            save([dir_name, file_name], 'data_save');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App startup function
            
            % add path
            addpath(genpath('./Algorithms/'));
            addpath(genpath('./Problems/'));
            addpath(genpath('./Utils/'));
            
            app.EloadAlgoProb();
            app.VresetTable({}, {}, []);
            
            % read default value
            app.Ereps = app.ERepsEditField.Value;
            app.Epop_size = app.EPopSizeEditField.Value;
            app.Eend_num = app.EEndNumEditField.Value;
        end

        % Value changed function: ERepsEditField
        function ERepsEditFieldValueChanged(app, event)
            % update reps parameter
            
            app.Ereps = app.ERepsEditField.Value;
        end

        % Value changed function: EPopSizeEditField
        function EPopSizeEditFieldValueChanged(app, event)
            % update pop_size parameter
            
            app.Epop_size = app.EPopSizeEditField.Value;
        end

        % Value changed function: EEndNumEditField
        function EEndNumEditFieldValueChanged(app, event)
            % update end_num parameter
            
            app.Eend_num = app.EEndNumEditField.Value;
        end

        % Context menu opening function: EAlgorithmsContextMenu
        function EAlgorithmsContextMenuOpening(app, event)
            % select all algorithms
            
            if ~isempty(app.EAlgorithmsListBox.Items)
                app.EAlgorithmsListBox.Value = app.EAlgorithmsListBox.Items;
            end
        end

        % Button pushed function: EAlgorithmsAddButton
        function EAlgorithmsAddButtonPushed(app, event)
            % add selected algorithms to selected algorithms tree
            
            algo_selected = app.EAlgorithmsListBox.Value;
            for i= 1:length(algo_selected)
                eval(['algo_obj = ', algo_selected{i}, '("', algo_selected{i}, '");']);
                algo_node = uitreenode(app.EAlgorithmsTree);
                algo_node.Text = algo_obj.getName();
                algo_node.NodeData = algo_obj;
                algo_node.ContextMenu = app.ESelectedAlgoContextMenu;
                
                % child parameter node
                parameter = algo_obj.getParameter();
                for p = 1:2:length(parameter)
                    para_name_node = uitreenode(algo_node);
                    para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                    para_name_node.NodeData = para_name_node.Text;
                    para_name_node.ContextMenu = app.ESelectedAlgoContextMenu;
                    para_value_node = uitreenode(algo_node);
                    para_value_node.Text = parameter{p+1};
                    para_value_node.ContextMenu = app.ESelectedAlgoContextMenu;
                end
            end
        end

        % Menu selected function: ProblemsSelectAllMenu
        function EProblemsContextMenuOpening(app, event)
            % select all problems
            
            if ~isempty(app.EProblemsListBox.Items)
                app.EProblemsListBox.Value = app.EProblemsListBox.Items;
            end
        end

        % Button pushed function: EProblemsAddButton
        function EProblemsAddButtonPushed(app, event)
            % add selected problems to selected problems tree
            
            prob_selected = app.EProblemsListBox.Value;
            for i= 1:length(prob_selected)
                eval(['prob_obj = ', prob_selected{i}, '("',prob_selected{i}, '");']);
                prob_node = uitreenode(app.EProblemsTree);
                prob_node.Text = prob_obj.getName();
                prob_node.NodeData = prob_obj;
                prob_node.ContextMenu = app.ESelectedProbContextMenu;
                
                % child parameter node
                parameter = prob_obj.getParameter();
                for p = 1:2:length(parameter)
                    para_name_node = uitreenode(prob_node);
                    para_name_node.Text = ['[ ', parameter{p}, ' ]'];
                    para_name_node.NodeData = para_name_node.Text;
                    para_name_node.ContextMenu = app.ESelectedProbContextMenu;
                    para_value_node = uitreenode(prob_node);
                    para_value_node.Text = parameter{p+1};
                    para_value_node.ContextMenu = app.ESelectedProbContextMenu;
                end
            end
        end

        % Button pushed function: EStartButton
        function EStartButtonPushed(app, event)
            % start this experiment
            
            % check selected
            algo_num = length(app.EAlgorithmsTree.Children);
            prob_num = length(app.EProblemsTree.Children);
            if algo_num == 0
                app.Eprintlog('!!! Please select the Algorithm first !!!');
                app.EstartEnable(true);
                return;
            end
            if prob_num == 0
                app.Eprintlog('!!! Please select the Problem first !!!');
                app.EstartEnable(true);
                return;
            end
            
            % off the start button
            app.EstartEnable(false);
            app.Estop_flag = false;
            
            % clear the temporary data
            app.data = [];
            app.Eresult = [];
            
            % initialize the result properties
            for algo = 1:algo_num
                for prob = 1:prob_num
                    app.Eresult(prob, algo).clock_time = 0;
                    app.Eresult(prob, algo).convergence = [];
                end
            end
            
            % read selected algorithms and problems
            algo_cell = {};
            for algo = 1:algo_num
                algo_cell{algo} = app.EAlgorithmsTree.Children(algo).Text;
            end
            prob_cell = {};
            for prob = 1:prob_num
                prob_cell{prob} = app.EProblemsTree.Children(prob).Text;
                tasks_num_list(prob) = app.EProblemsTree.Children(prob).NodeData.getTasksNumber();
            end
            
            app.VresetTable(algo_cell, prob_cell, tasks_num_list);
            
            % main test loop
            log_str = [newline, '#====== Experiments Start ======#'];
            app.Eprintlog(log_str);
            app.Vprintlog(log_str);
            app.Eprintlog('== See result in <View Table> ==')
            tStart = tic;
            for rep = 1:app.Ereps
                log_str = ['<======== Rep: ', num2str(rep), ' ========>'];
                app.Eprintlog(log_str);
                app.Vprintlog(log_str);
                for prob = 1:prob_num
                    log_str = ['-------- Problem: ', app.EProblemsTree.Children(prob).Text, ' --------'];
                    app.Eprintlog(log_str);
                    app.Vprintlog(log_str);
                    for algo = 1:algo_num
                        % check pause and stop
                        app.EcheckPauseStopStatus();
                        
                        % get this experiment's parameters
                        log_str = [app.EAlgorithmsTree.Children(algo).Text, ' is running'];
                        app.Eprintlog(log_str);
                        app.Vprintlog(log_str);
                        switch app.EEndConditionDropDown.Value
                            case 'Iteration'
                                iter_num = app.Eend_num;
                                eva_num = inf;
                            case 'Evaluation'
                                iter_num = inf;
                                eva_num = app.Eend_num;
                        end
                        pre_run_list = [app.Epop_size, iter_num, eva_num];
                        
                        % run
                        data = singleRun(app.EAlgorithmsTree.Children(algo).NodeData, app.EProblemsTree.Children(prob).NodeData, pre_run_list);
                        app.Eresult(prob, algo).clock_time = app.Eresult(prob, algo).clock_time + data.clock_time;
                        % BUG: when p_il ~= 0, convergence vartical not same
                        app.Eresult(prob, algo).convergence = [app.Eresult(prob, algo).convergence; data.convergence];
                    end
                    app.VupdateTableProb(algo_cell, prob, tasks_num_list);
                end
                
                app.data.reps = rep;
                app.data.tasks_num_list = tasks_num_list;
                app.data.pop_size = app.Epop_size;
                app.data.iter_num = iter_num;
                app.data.eva_num = eva_num;
                app.data.algo_cell = algo_cell;
                app.data.prob_cell = prob_cell';
                app.data.result = app.Eresult;
            end
            
            tEnd = toc(tStart);
            log_str = ['<----- All Use Time: ', char(duration([0, 0, tEnd])), ' ----->'];
            app.Eprintlog(log_str);
            app.Vprintlog(log_str);
            log_str = ['#==== Experiments Finished ====#', newline];
            app.Eprintlog(log_str);
            app.Vprintlog(log_str);
            
            app.EstartEnable(true);
        end

        % Button pushed function: EPauseButton
        function EPauseButtonPushed(app, event)
            % pause or resume this experiment
            
            if strcmp(app.EPauseButton.Text, 'Pause')
                app.EStopButton.Enable = 'off';
                app.EPauseButton.Text = 'Resume';
                log_str = '########### Paused ###########';
                app.Eprintlog(log_str);
                app.Vprintlog(log_str);
            else
                app.EStopButton.Enable = 'on';
                app.EPauseButton.Text = 'Pause';
                log_str = '########## Resumed ##########';
                app.Eprintlog(log_str);
                app.Vprintlog(log_str);
            end
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            % stop this experiment
            
            app.EstartEnable(true);
            app.Estop_flag = true;
            log_str = '#====== Experiments Stopped ======#';
            app.Eprintlog(log_str);
            app.Vprintlog(log_str);
        end

        % Context menu opening function: ESelectedAlgoContextMenu
        function ESelectedAlgoContextMenuOpening(app, event)
            % select all selected algorithms
            
            if ~isempty(app.EAlgorithmsTree.Children)
                app.EAlgorithmsTree.SelectedNodes = app.EAlgorithmsTree.Children;
            end
        end

        % Button pushed function: EAlgorithmsDelButton
        function EAlgorithmsDelButtonPushed(app, event)
            % delete selected algorithms from algorithms tree
            
            algo_selected = app.EAlgorithmsTree.SelectedNodes;
            if isempty(algo_selected)
                app.Eprintlog('!!! Select Algorithm node in tree first !!!');
            end
            
            for i = 1:length(algo_selected)
                if isa(algo_selected(i).Parent, 'matlab.ui.container.Tree')
                    algo_selected(i).delete;
                end
            end
        end

        % Node text changed function: EAlgorithmsTree
        function EAlgorithmsTreeNodeTextChanged(app, event)
            % update algorithm obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is algorithm name node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end

        % Menu selected function: SelectedProbSelectAllMenu
        function ESelectedProbContextMenuOpening(app, event)
            % select all selected problems
            
            if ~isempty(app.EProblemsTree.Children)
                app.EProblemsTree.SelectedNodes = app.EProblemsTree.Children;
            end
        end

        % Button pushed function: EProblemsDelButton
        function EProblemsDelButtonPushed(app, event)
            % delete selected problems from problems tree
            
            prob_selected = app.EProblemsTree.SelectedNodes;
            if isempty(prob_selected)
                app.Eprintlog('!!! Select Problem node in tree first !!!');
            end
            
            for i = 1:length(prob_selected)
                if isa(prob_selected(i).Parent, 'matlab.ui.container.Tree')
                    prob_selected(i).delete;
                end
            end
        end

        % Node text changed function: EProblemsTree
        function EProblemsTreeNodeTextChanged(app, event)
            % update problem obj parameter
            
            node = event.Node;
            if isa(node.Parent, 'matlab.ui.container.Tree')
                % this is problem node
                node.NodeData.name = node.Text;
            else
                % this is parameter node
                parameter = {};
                % the first node text is parameter name, can't change
                for x = 1:2:length(node.Parent.Children)
                    node.Parent.Children(x).Text = node.Parent.Children(x).NodeData;
                end
                % the second node text is parameter value
                for x = 2:2:length(node.Parent.Children)
                    parameter = [parameter, node.Parent.Children(x).Text];
                end
                node.Parent.NodeData.setParameter(parameter);
            end
        end

        % Button pushed function: ELogsClearButton
        function ELogsClearButtonPushed(app, event)
            % clear logs text area
            
            app.ELogsTextArea.Value = '';
        end

        % Button pushed function: ESaveDataButton
        function ESaveDataButtonPushed(app, event)
            % save data to folder
            
            % check data
            if isempty(app.data)
                app.Eprintlog('!!! Please run experiment first !!!');
                return;
            end
            
            % check selected file name
            [file_name, dir_name] = uiputfile('data_save.mat');
            if file_name == 0
                app.Eprintlog('!!! User select canceled !!!');
                return;
            end
            
            % save data
            data_save = app.data;
            save([dir_name, file_name], 'data_save');
        end

        % Button pushed function: TLogsClearButton
        function TLogsClearButtonPushed(app, event)
            % clear logs text area
            
            app.TLogsTextArea.Value = '';
        end

        % Button pushed function: VLogsClearButton
        function VLogsClearButtonPushed(app, event)
            % clear logs text area
            
            app.VLogsTextArea.Value = '';
        end

        % Button pushed function: VLoadDataButton
        function VLoadDataButtonPushed(app, event)
            % load data from file
            
            % select mat file
            [file_name, pathname] = uigetfile('*.mat', 'Select Data', './');
            
            % check selected ile_name
            if file_name == 0
                app.Vprintlog('!!! User select canceled !!!');
                return;
            end
            
            % load data to app's parameter
            load([pathname, file_name], 'data_save');
            app.Vprintlog(['Load Data: "', [pathname, file_name], '"']);
            app.data = data_save;
            app.VresetTable(app.data.algo_cell, app.data.prob_cell, app.data.tasks_num_list);
            app.VupdateTable(app.data);
        end

        % Button pushed function: VSaveResultButton
        function VSaveResultButtonPushed(app, event)
            % save data, figure, table to folder
            
            % check data
            if isempty(app.data)
                app.Vprintlog('!!! Please run experiment or load data first !!!');
                return;
            end
            
            % check selected dir name
            dir_name = uigetdir('./', 'Select save path');
            if dir_name == 0
                app.Vprintlog('!!! User select canceled !!!');
                return;
            end
            
            % save data
            data_save = app.data;
            save([dir_name, '/data_save'], 'data_save');
            
            % save figure
            fig_dir_name = [dir_name, '/data_Figure/'];
            mkdir(fig_dir_name);
            draw_obj = drawFigure;
            for prob = 1:length(data_save.prob_cell)
                tasks_num = data_save.tasks_num_list(prob);
                for task = 1:tasks_num
                    for algo = 1:length(data_save.algo_cell)
                        convergence_task = data_save.result(prob, algo).convergence(task:tasks_num:end, :);
                        convergence = mean(convergence_task, 1);
                        x_cell{algo} = 1:size(convergence,2);
                        y_cell{algo} = convergence;
                    end
                    switch app.VYLimTypeDropDown.Value
                        case 'log(fitness)'
                            for i = 1:length(y_cell)
                                y_cell{i} = log(y_cell{i});
                            end
                    end
                    draw_obj.setXY(x_cell, y_cell);
                    draw_obj.setXYlabel('Generation', app.VYLimTypeDropDown.Value);
                    draw_obj.setLegend(data_save.algo_cell);
                    draw_obj.setTitle([data_save.prob_cell{prob}, ' T', num2str(task)]);
                    draw_obj.setSaveDir(fig_dir_name);
                    draw_obj.setFigureType(app.VFigureTypeDropDown.Value);
                    draw_obj.save();
                end
            end
            
            % save table
            table_dir_name = [dir_name, '/data_Table/'];
            mkdir(table_dir_name);
            % add algo and prob name
            prob_task_cell = {};
            for prob = 1:length(data_save.prob_cell)
                tasks_num = data_save.tasks_num_list(prob);
                for task = 1:tasks_num
                    prob_task_cell = [prob_task_cell, [data_save.prob_cell{prob}, num2str(task)]];
                end
            end
            cell_out = [[{''}, prob_task_cell]', [data_save.algo_cell; num2cell(app.TFitnessUITable.Data)]];
            writecell(cell_out, [table_dir_name, 'Fitness.', app.VTableTypeDropDown.Value]);
            cell_out = [[{''}, prob_task_cell]', [data_save.algo_cell; num2cell(app.TFitnessStdUITable.Data)]];
            writecell(cell_out, [table_dir_name, 'Fitness Std.', app.VTableTypeDropDown.Value]);
            cell_out = [[{''}, data_save.prob_cell']', [data_save.algo_cell; num2cell(app.TTimeUITable.Data)]];
            writecell(cell_out, [table_dir_name, 'Time Used.', app.VTableTypeDropDown.Value]);
            app.Vprintlog(['Save to: "', dir_name, '"']);
        end

        % Button pushed function: MLogsClearButton
        function MLogsClearButtonPushed(app, event)
            % clear logs text area
            
            app.MLogsTextArea.Value = '';
        end

        % Button pushed function: MLoadDataButton
        function MLoadDataButtonPushed(app, event)
            % load data from mat files
            
            % select mat file
            file_name_list = {};
            [file_name, pathname] = uigetfile('*.mat', 'select the data mat', './', 'MultiSelect', 'on');
            file_name_list = [file_name_list, file_name];
            
            % check selected file_name
            if file_name_list{1} == 0
                app.Mprintlog('!!! User select canceled !!!');
                return;
            end
            
            %load data mat files
            for i = 1:length(file_name_list)
                data_node = uitreenode(app.MDataTree);
                data_node.Text = file_name_list{i};
                load([pathname, file_name_list{i}], 'data_save');
                app.Mprintlog(['Load data: "', [pathname, file_name_list{i}], '"']);
                data_node.NodeData = data_save;
                data_node.ContextMenu = app.MDataContextMenu;
                
                % child node
                reps_node = uitreenode(data_node);
                reps_node.Text = ['Reps: ', num2str(data_node.NodeData.reps)];
                reps_node.ContextMenu = app.MDataContextMenu;
                
                algo_node = uitreenode(data_node);
                algo_node.Text = 'Algorithms:';
                algo_node.ContextMenu = app.MDataContextMenu;
                for algo = 1:length(data_node.NodeData.algo_cell)
                    algo_child_node = uitreenode(algo_node);
                    algo_child_node.Text = data_node.NodeData.algo_cell{algo};
                    algo_child_node.ContextMenu = app.MDataContextMenu;
                end
                
                prob_node = uitreenode(data_node);
                prob_node.Text = 'Problems:';
                prob_node.ContextMenu = app.MDataContextMenu;
                for prob = 1:length(data_node.NodeData.prob_cell)
                    prob_child_node = uitreenode(prob_node);
                    prob_child_node.Text = data_node.NodeData.prob_cell{prob};
                    prob_child_node.ContextMenu = app.MDataContextMenu;
                end
                
                pop_node = uitreenode(data_node);
                pop_node.Text = ['Pop Size: ', num2str(data_node.NodeData.pop_size)];
                pop_node.ContextMenu = app.MDataContextMenu;
                
                iter_node = uitreenode(data_node);
                iter_node.Text = ['Iteration Num: ', num2str(data_node.NodeData.iter_num)];
                iter_node.ContextMenu = app.MDataContextMenu;
                
                eva_node = uitreenode(data_node);
                eva_node.Text = ['Evaluation Num: ', num2str(data_node.NodeData.eva_num)];
                eva_node.ContextMenu = app.MDataContextMenu;
            end
        end

        % Context menu opening function: MDataContextMenu
        function MDataContextMenuOpening(app, event)
            % select all data
            
            if ~isempty(app.MDataTree.Children)
                app.MDataTree.SelectedNodes = app.MDataTree.Children;
            end
        end

        % Button pushed function: MDeleteDataButton
        function MDeleteDataButtonPushed(app, event)
            % delete selected data from data tree
            
            data_selected = app.MDataTree.SelectedNodes;
            if isempty(data_selected)
                app.Mprintlog('!!! Select data node in tree first !!!');
            end
            
            for i = 1:length(data_selected)
                if isa(data_selected(i).Parent, 'matlab.ui.container.Tree')
                    data_selected(i).delete;
                end
            end
        end

        % Button pushed function: MRepsMergeButton
        function MRepsMergeButtonPushed(app, event)
            % merge reps, with same pop, iteration, evaluate, algorithms and problems
            
            if ~app.McheckData() || ~app.McheckAlgorithms() || ~app.McheckProblems()
                return;
            end
            
            % merge
            data_save.reps = 0;
            data_save.tasks_num_list = app.MDataTree.Children(1).NodeData.tasks_num_list;
            data_save.pop_size = app.MDataTree.Children(1).NodeData.pop_size;
            data_save.iter_num = app.MDataTree.Children(1).NodeData.iter_num;
            data_save.eva_num = app.MDataTree.Children(1).NodeData.eva_num;
            data_save.algo_cell = app.MDataTree.Children(1).NodeData.algo_cell;
            data_save.prob_cell = app.MDataTree.Children(1).NodeData.prob_cell;
            for prob = 1:length(data_save.prob_cell)
                for algo = 1:length(data_save.algo_cell)
                    data_save.result(prob, algo).clock_time = 0;
                    data_save.result(prob, algo).convergence = [];
                end
            end
            for i = 1:length(app.MDataTree.Children)
                data_save.reps = data_save.reps + app.MDataTree.Children(i).NodeData.reps;
                for prob = 1:length(data_save.prob_cell)
                    for algo = 1:length(data_save.algo_cell)
                        data_save.result(prob, algo).clock_time = data_save.result(prob, algo).clock_time + app.MDataTree.Children(i).NodeData.result(prob, algo).clock_time;
                        % BUG: when p_il ~= 0, convergence vartical not same
                        data_save.result(prob, algo).convergence = [data_save.result(prob, algo).convergence; app.MDataTree.Children(i).NodeData.result(prob, algo).convergence];
                    end
                end
            end
            
            app.MsaveData(data_save);
        end

        % Button pushed function: MAlgorithmsMergeButton
        function MAlgorithmsMergeButtonPushed(app, event)
            % merge algorithms, with same pop, iteration, evaluate, reps and problems
            
            if ~app.McheckData() || ~app.McheckReps() || ~app.McheckProblems()
                return;
            end
            
            % merge
            data_save.reps = app.MDataTree.Children(1).NodeData.reps;
            data_save.tasks_num_list = app.MDataTree.Children(1).NodeData.tasks_num_list;
            data_save.pop_size = app.MDataTree.Children(1).NodeData.pop_size;
            data_save.iter_num = app.MDataTree.Children(1).NodeData.iter_num;
            data_save.eva_num = app.MDataTree.Children(1).NodeData.eva_num;
            data_save.prob_cell = app.MDataTree.Children(1).NodeData.prob_cell;
            data_save.algo_cell = {};
            for i = 1:length(app.MDataTree.Children)
                algo_start = length(data_save.algo_cell) + 1;
                data_save.algo_cell = [data_save.algo_cell, app.MDataTree.Children(i).NodeData.algo_cell];
                algo_end = length(data_save.algo_cell);
                data_save.result(:, algo_start:algo_end) = app.MDataTree.Children(i).NodeData.result;
            end
            
            app.MsaveData(data_save);
        end

        % Button pushed function: MProblemsMergeButton
        function MProblemsMergeButtonPushed(app, event)
            % merge problems, with same pop, iteration, evaluate, reps and algorithms
            
            if ~app.McheckData() || ~app.McheckReps() || ~app.McheckAlgorithms()
                return;
            end
            
            % merge
            data_save.reps = app.MDataTree.Children(1).NodeData.reps;
            data_save.pop_size = app.MDataTree.Children(1).NodeData.pop_size;
            data_save.iter_num = app.MDataTree.Children(1).NodeData.iter_num;
            data_save.eva_num = app.MDataTree.Children(1).NodeData.eva_num;
            data_save.algo_cell = app.MDataTree.Children(1).NodeData.algo_cell;
            data_save.prob_cell = {};
            data_save.tasks_num_list = [];
            for i = 1:length(app.MDataTree.Children)
                data_save.tasks_num_list = [data_save.tasks_num_list, app.MDataTree.Children(i).NodeData.tasks_num_list];
                prob_start = length(data_save.prob_cell) + 1;
                data_save.prob_cell = [data_save.prob_cell; app.MDataTree.Children(i).NodeData.prob_cell];
                prob_end = length(data_save.prob_cell);
                data_save.result(prob_start:prob_end, :) = app.MDataTree.Children(i).NodeData.result;
            end
            
            app.MsaveData(data_save);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MTOPlatformUIFigure = uifigure('Visible', 'off');
            app.MTOPlatformUIFigure.Name = 'MTO Platform';
            app.MTOPlatformGridLayout = uigridlayout(app.MTOPlatformUIFigure);
            app.MTOPlatformTabGroup = uitabgroup(app.MTOPlatformGridLayout);
            app.ExperimentModelTab = uitab(app.MTOPlatformTabGroup);
            app.ExperimentModelTab.Title = 'Experiment Model';
            app.ExperimentsGridLayout = uigridlayout(app.ExperimentModelTab);
            app.EPanel3 = uipanel(app.ExperimentsGridLayout);
            app.EPanel3GridLayout = uigridlayout(app.EPanel3);
            app.LogsTextAreaLabel = uilabel(app.EPanel3GridLayout);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.ELogsTextArea = uitextarea(app.EPanel3GridLayout);
            app.ELogsTextArea.Editable = 'off';
            app.ELogsClearButton = uibutton(app.EPanel3GridLayout, 'push');
            app.ELogsClearButton.ButtonPushedFcn = createCallbackFcn(app, @ELogsClearButtonPushed, true);
            app.ELogsClearButton.Text = 'Clear';
            app.ESaveDataButton = uibutton(app.EPanel3GridLayout, 'push');
            app.ESaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @ESaveDataButtonPushed, true);
            app.ESaveDataButton.Text = 'Save Data';
            app.EPanel1 = uipanel(app.ExperimentsGridLayout);
            app.EP1GridLayout = uigridlayout(app.EPanel1);
            app.EProblemsAddButton = uibutton(app.EP1GridLayout, 'push');
            app.EProblemsAddButton.ButtonPushedFcn = createCallbackFcn(app, @EProblemsAddButtonPushed, true);
            app.EProblemsAddButton.Text = 'Add';
            app.EAlgorithmsAddButton = uibutton(app.EP1GridLayout, 'push');
            app.EAlgorithmsAddButton.ButtonPushedFcn = createCallbackFcn(app, @EAlgorithmsAddButtonPushed, true);
            app.EAlgorithmsAddButton.Text = 'Add';
            app.ERepsEditField = uieditfield(app.EP1GridLayout, 'numeric');
            app.ERepsEditField.ValueChangedFcn = createCallbackFcn(app, @ERepsEditFieldValueChanged, true);
            app.ERepsEditField.Value = 20;
            app.ERunTimesEditFieldLabel = uilabel(app.EP1GridLayout);
            app.ERunTimesEditFieldLabel.Text = 'Run Times';
            app.EEndNumEditField = uieditfield(app.EP1GridLayout, 'numeric');
            app.EEndNumEditField.ValueChangedFcn = createCallbackFcn(app, @EEndNumEditFieldValueChanged, true);
            app.EEndNumEditField.Value = 1000;
            app.EEndNumEditFieldLabel = uilabel(app.EP1GridLayout);
            app.EEndNumEditFieldLabel.Text = 'End Num';
            app.EPopSizeEditField = uieditfield(app.EP1GridLayout, 'numeric');
            app.EPopSizeEditField.ValueChangedFcn = createCallbackFcn(app, @EPopSizeEditFieldValueChanged, true);
            app.EPopSizeEditField.Value = 100;
            app.EPopSizeEditFieldLabel = uilabel(app.EP1GridLayout);
            app.EPopSizeEditFieldLabel.Text = 'Pop Size';
            app.EAlgorithmsListBox = uilistbox(app.EP1GridLayout);
            app.EAlgorithmsListBox.Items = {};
            app.EAlgorithmsListBox.Multiselect = 'on';
            app.EAlgorithmsListBox.Value = {};
            app.AlgorithmsListBox_2Label = uilabel(app.EP1GridLayout);
            app.AlgorithmsListBox_2Label.Text = 'Algorithms';
            app.EProblemsListBox = uilistbox(app.EP1GridLayout);
            app.EProblemsListBox.Items = {};
            app.EProblemsListBox.Multiselect = 'on';
            app.EProblemsListBox.Value = {};
            app.ProblemsListBox_2Label = uilabel(app.EP1GridLayout);
            app.ProblemsListBox_2Label.Text = 'Problems';
            app.EndTypeLabel = uilabel(app.EP1GridLayout);
            app.EndTypeLabel.Text = 'End Type';
            app.EEndConditionDropDown = uidropdown(app.EP1GridLayout);
            app.EEndConditionDropDown.Items = {'Iteration', 'Evaluation'};
            app.EEndConditionDropDown.Value = 'Iteration';
            app.EPanel2 = uipanel(app.ExperimentsGridLayout);
            app.EP2GridLayout = uigridlayout(app.EPanel2);
            app.EStartButton = uibutton(app.EP2GridLayout, 'push');
            app.EStartButton.ButtonPushedFcn = createCallbackFcn(app, @EStartButtonPushed, true);
            app.EStartButton.Text = 'Start';
            app.EPauseButton = uibutton(app.EP2GridLayout, 'push');
            app.EPauseButton.ButtonPushedFcn = createCallbackFcn(app, @EPauseButtonPushed, true);
            app.EPauseButton.Enable = 'off';
            app.EPauseButton.Text = 'Pause';
            app.EStopButton = uibutton(app.EP2GridLayout, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Enable = 'off';
            app.EStopButton.Text = 'Stop';
            app.EAlgorithmsTree = uitree(app.EP2GridLayout);
            app.EAlgorithmsTree.Multiselect = 'on';
            app.EAlgorithmsTree.NodeTextChangedFcn = createCallbackFcn(app, @EAlgorithmsTreeNodeTextChanged, true);
            app.EAlgorithmsTree.Editable = 'on';
            app.EProblemsTree = uitree(app.EP2GridLayout);
            app.EProblemsTree.Multiselect = 'on';
            app.EProblemsTree.NodeTextChangedFcn = createCallbackFcn(app, @EProblemsTreeNodeTextChanged, true);
            app.EProblemsTree.Editable = 'on';
            app.ESelectedAlgorithmsLabel = uilabel(app.EP2GridLayout);
            app.ESelectedAlgorithmsLabel.Text = 'Selected Algorithms';
            app.ESelectedProblemsLabel = uilabel(app.EP2GridLayout);
            app.ESelectedProblemsLabel.Text = 'Selected Problems';
            app.EAlgorithmsDelButton = uibutton(app.EP2GridLayout, 'push');
            app.EAlgorithmsDelButton.ButtonPushedFcn = createCallbackFcn(app, @EAlgorithmsDelButtonPushed, true);
            app.EAlgorithmsDelButton.Text = 'Delete';
            app.EProblemsDelButton = uibutton(app.EP2GridLayout, 'push');
            app.EProblemsDelButton.ButtonPushedFcn = createCallbackFcn(app, @EProblemsDelButtonPushed, true);
            app.EProblemsDelButton.Text = 'Delete';
            app.ViewTableTab = uitab(app.MTOPlatformTabGroup);
            app.ViewTableTab.Title = 'View Table';
            app.ViewTableGridLayout = uigridlayout(app.ViewTableTab);
            app.VPanel1 = uipanel(app.ViewTableGridLayout);
            app.VP1GridLayout = uigridlayout(app.VPanel1);
            app.VTableTabGroup = uitabgroup(app.VP1GridLayout);
            app.VFitnessTab = uitab(app.VTableTabGroup);
            app.VFitnessTab.Title = 'Fitness';
            app.TFitnessGridLayout = uigridlayout(app.VFitnessTab);
            app.TFitnessUITable = uitable(app.TFitnessGridLayout);
            app.TFitnessUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TFitnessUITable.RowName = {};
            app.VFitnessStdTab = uitab(app.VTableTabGroup);
            app.VFitnessStdTab.Title = 'Fitness Std';
            app.TFitnessStdGridLayout = uigridlayout(app.VFitnessStdTab);
            app.TFitnessStdUITable = uitable(app.TFitnessStdGridLayout);
            app.TFitnessStdUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TFitnessStdUITable.RowName = {};
            app.VTimeTab = uitab(app.VTableTabGroup);
            app.VTimeTab.Title = 'Time Used';
            app.TTimeTableGridLayout = uigridlayout(app.VTimeTab);
            app.TTimeUITable = uitable(app.TTimeTableGridLayout);
            app.TTimeUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TTimeUITable.RowName = {};
            app.VPanel2 = uipanel(app.ViewTableGridLayout);
            app.VP2GridLayout = uigridlayout(app.VPanel2);
            app.VLogsTextArea = uitextarea(app.VP2GridLayout);
            app.VLogsTextArea.Editable = 'off';
            app.VLogsClearButton = uibutton(app.VP2GridLayout, 'push');
            app.VLogsClearButton.ButtonPushedFcn = createCallbackFcn(app, @VLogsClearButtonPushed, true);
            app.VLogsClearButton.Text = 'Clear';
            app.VLogsTextAreaLabel = uilabel(app.VP2GridLayout);
            app.VLogsTextAreaLabel.Text = 'Logs';
            app.VPanel3 = uipanel(app.ViewTableGridLayout);
            app.VP3GridLayout = uigridlayout(app.VPanel3);
            app.YLimTypeDropDownLabel = uilabel(app.VP3GridLayout);
            app.YLimTypeDropDownLabel.Text = 'YLim Type';
            app.VYLimTypeDropDown = uidropdown(app.VP3GridLayout);
            app.VYLimTypeDropDown.Items = {'log(fitness)', 'fitness'};
            app.VYLimTypeDropDown.Value = 'log(fitness)';
            app.FigureTypeDropDownLabel = uilabel(app.VP3GridLayout);
            app.FigureTypeDropDownLabel.Text = 'Figure Type';
            app.VFigureTypeDropDown = uidropdown(app.VP3GridLayout);
            app.VFigureTypeDropDown.Items = {'png', 'eps', 'pdf'};
            app.VFigureTypeDropDown.Value = 'png';
            app.TableTypeDropDownLabel = uilabel(app.VP3GridLayout);
            app.TableTypeDropDownLabel.Text = 'Table Type';
            app.VTableTypeDropDown = uidropdown(app.VP3GridLayout);
            app.VTableTypeDropDown.Items = {'csv', 'xlsx'};
            app.VTableTypeDropDown.Value = 'csv';
            app.VSaveResultButton = uibutton(app.VP3GridLayout, 'push');
            app.VSaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @VSaveResultButtonPushed, true);
            app.VSaveResultButton.Text = 'Save Result';
            app.VLoadDataButton = uibutton(app.VP3GridLayout, 'push');
            app.VLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @VLoadDataButtonPushed, true);
            app.VLoadDataButton.Text = 'Load Data';
            app.TestModelTab = uitab(app.MTOPlatformTabGroup);
            app.TestModelTab.Title = 'Test Model';
            app.TestGridLayout = uigridlayout(app.TestModelTab);
            app.TPGridLayout = uigridlayout(app.TestGridLayout);
            app.LogsTextArea_2Label_2 = uilabel(app.TPGridLayout);
            app.LogsTextArea_2Label_2.Text = 'Logs';
            app.TLogsTextArea = uitextarea(app.TPGridLayout);
            app.TLogsTextArea.Editable = 'off';
            app.TLogsClearButton = uibutton(app.TPGridLayout, 'push');
            app.TLogsClearButton.ButtonPushedFcn = createCallbackFcn(app, @TLogsClearButtonPushed, true);
            app.TLogsClearButton.Text = 'Clear';
            app.MergeDataTab = uitab(app.MTOPlatformTabGroup);
            app.MergeDataTab.Title = 'Merge Data';
            app.MergeDataGridLayout = uigridlayout(app.MergeDataTab);
            app.MPanel1 = uipanel(app.MergeDataGridLayout);
            app.MP1GridLayout = uigridlayout(app.MPanel1);
            app.MLoadDataButton = uibutton(app.MP1GridLayout, 'push');
            app.MLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @MLoadDataButtonPushed, true);
            app.MLoadDataButton.Text = 'Load Data';
            app.MDeleteDataButton = uibutton(app.MP1GridLayout, 'push');
            app.MDeleteDataButton.ButtonPushedFcn = createCallbackFcn(app, @MDeleteDataButtonPushed, true);
            app.MDeleteDataButton.Text = 'Delete Data';
            app.MDataTree = uitree(app.MP1GridLayout);
            app.MDataTree.Multiselect = 'on';
            app.MRepsMergeButton = uibutton(app.MP1GridLayout, 'push');
            app.MRepsMergeButton.ButtonPushedFcn = createCallbackFcn(app, @MRepsMergeButtonPushed, true);
            app.MRepsMergeButton.Text = 'Reps Merge';
            app.MProblemsMergeButton = uibutton(app.MP1GridLayout, 'push');
            app.MProblemsMergeButton.ButtonPushedFcn = createCallbackFcn(app, @MProblemsMergeButtonPushed, true);
            app.MProblemsMergeButton.Text = 'Problems Merge';
            app.MAlgorithmsMergeButton = uibutton(app.MP1GridLayout, 'push');
            app.MAlgorithmsMergeButton.ButtonPushedFcn = createCallbackFcn(app, @MAlgorithmsMergeButtonPushed, true);
            app.MAlgorithmsMergeButton.Text = 'Algorithms Merge';
            app.MPanel2 = uipanel(app.MergeDataGridLayout);
            app.MP2GridLayout = uigridlayout(app.MPanel2);
            app.LogsTextArea_2Label = uilabel(app.MP2GridLayout);
            app.LogsTextArea_2Label.Text = 'Logs';
            app.MLogsTextArea = uitextarea(app.MP2GridLayout);
            app.MLogsTextArea.Editable = 'off';
            app.MLogsClearButton = uibutton(app.MP2GridLayout, 'push');
            app.MLogsClearButton.ButtonPushedFcn = createCallbackFcn(app, @MLogsClearButtonPushed, true);
            app.MLogsClearButton.Text = 'Clear';
            app.ESelectedAlgoContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.ESelectedAlgoContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ESelectedAlgoContextMenuOpening, true);
            % Assign app.ESelectedAlgoContextMenu
            app.EAlgorithmsTree.ContextMenu = app.ESelectedAlgoContextMenu;
            app.SelectedAlgoSelectAllMenu = uimenu(app.ESelectedAlgoContextMenu);
            app.SelectedAlgoSelectAllMenu.Text = 'Select All';
            app.MDataContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.MDataContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @MDataContextMenuOpening, true);
            % Assign app.MDataContextMenu
            app.MDataTree.ContextMenu = app.MDataContextMenu;
            app.SelectedAlgoSelectAllMenu_2 = uimenu(app.MDataContextMenu);
            app.SelectedAlgoSelectAllMenu_2.Text = 'Select All';
            app.ESelectedProbContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            % Assign app.ESelectedProbContextMenu
            app.EProblemsTree.ContextMenu = app.ESelectedProbContextMenu;
            app.SelectedProbSelectAllMenu = uimenu(app.ESelectedProbContextMenu);
            app.SelectedProbSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ESelectedProbContextMenuOpening, true);
            app.SelectedProbSelectAllMenu.Text = 'Select All';
            app.EAlgorithmsContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            app.EAlgorithmsContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @EAlgorithmsContextMenuOpening, true);
            % Assign app.EAlgorithmsContextMenu
            app.EAlgorithmsListBox.ContextMenu = app.EAlgorithmsContextMenu;
            app.AlgorithmsSelectAllMenu = uimenu(app.EAlgorithmsContextMenu);
            app.AlgorithmsSelectAllMenu.Text = 'Select All';
            app.EProblemsContextMenu = uicontextmenu(app.MTOPlatformUIFigure);
            % Assign app.EProblemsContextMenu
            app.EProblemsListBox.ContextMenu = app.EProblemsContextMenu;
            app.ProblemsSelectAllMenu = uimenu(app.EProblemsContextMenu);
            app.ProblemsSelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @EProblemsContextMenuOpening, true);
            app.ProblemsSelectAllMenu.Text = 'Select All';
            % Show the figure after all components are created
            app.MTOPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTO_Platform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MTOPlatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MTOPlatformUIFigure)
        end
    end
end